classdef MultiConverter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Title                matlab.ui.control.Label
        Image                matlab.ui.control.Image
        cvs                  matlab.ui.control.EditField
        NamethecsvfileLabel  matlab.ui.control.Label
        UITable              matlab.ui.control.Table
        ExportButton         matlab.ui.control.Button
        LoadButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        RawFileFolder % Path containing the .raw or .h5 files
        RootFolder    % Path containing the main folder of the experiment
        System        % system used to acquire the data
        fs double     % sample frequency used to acquire the data
        indices       % ONLY for MCS, variable containing the indices of the file that you want to convert
        
    end
    
    methods (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            %% Select the folder containing the .raw or .h5 files
            app.RawFileFolder = uigetdir('C:\','Select the folder that contains .RAW files to convert');
            cd(app.RawFileFolder);
            listin = dir;
            for i = 3:length(listin)
                FileName{i-2,1} = listin(i).name;
            end
            % print the file in the UI table 
            T = table(FileName);
            app.UITable.Data = T;
            
            %% Set parameters for Axion or MCS
            % Get the file extension 
            file = listin(3).name;
            [filepath,name,ext] = fileparts(file);
            % switch between HD5F5 and RAW
            if strcmp(ext,".raw")
                app.fs = 12500;
                app.System = "Axion";    
            elseif strcmp(ext,".h5")
                app.fs = 10000;
                app.System = "MCS";
            else

            end


        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
                %% Going to the root (main folder of the exoeriment)
                app.RootFolder = uigetdir('C:\','Select the root older of your experiment');
                cd(app.RootFolder)
                
                %% Check if exist and/or Create the folder to store the .mat raw data
                if exist('RawMatFiles','dir')
                    % do some warning / error
                    cd('RawMatFiles')
                    RawMatFiles_folder = pwd;
                    cd(app.RootFolder)
                else
                    mkdir('RawMatFiles')
                    cd('RawMatFiles')
                    RawMatFiles_folder = pwd;
                    cd(app.RootFolder)
                end
                 
                %% Call the relative convering function
                switch app.System       
                    
                    case "Axion" 
                    
                    ConvertAxionRaw(app.RawFileFolder,RawMatFiles_folder,app.fs)

                    case "MCS"
                    
                    converterMCS(app.RawFileFolder,RawMatFiles_folder,app.fs,app.indices)
                    
                end
            
                
        end

        % Callback function
        function UITableSelectionChanged(app, event)
             app.UITable.Selection;
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.indices = event.Indices;
            app.indices = app.indices(:,1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'File Name'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.NamethecsvfileLabel = uilabel(app.UIFigure);
            app.NamethecsvfileLabel.Text = 'Name the csv file ';
            app.cvs = uieditfield(app.UIFigure, 'text');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo.png';
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = {'This App convert  Raw Data into mat files '; 'suitable for MEA-NAP analysis'; 'Create CSV file using the raw filename'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MultiConverter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end